classdef FlexBenchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab                matlab.ui.container.Tab
        ConfiguracinTab        matlab.ui.container.Tab
        SetHeigthsButton       matlab.ui.control.Button
        DownButton             matlab.ui.control.Button
        UpButton               matlab.ui.control.Button
        Set0Button             matlab.ui.control.Button
        SetMinButton           matlab.ui.control.Button
        SetMaxButton           matlab.ui.control.Button
        ComandEditField        matlab.ui.control.EditField
        ComandEditFieldLabel   matlab.ui.control.Label
        MonitorTextArea        matlab.ui.control.TextArea
        ConnectLamp            matlab.ui.control.Lamp
        ConnectButton          matlab.ui.control.Button
        BaudRateDropDown       matlab.ui.control.DropDown
        BaudRateDropDownLabel  matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
    ESP32           % El puerto serie
    TimerDatos      % El cronómetro para pedir datos
    
    % Las alturas del ciclo
    AlturaCero = 0
    AlturaMax = 0
    AlturaMin = 0
    
    end
    
    methods (Access = private)
        
        function leerMensajeSerie(app, src, ~)
            try
                mensaje = readline(src);
            
                % 1. Si es un dato de RESISTENCIA
                if startsWith(mensaje, "RES: ")
                    resistencia = str2double(extractAfter(mensaje, "RES: "));

                % 2. Si es un dato de POSICIÓN (para configurar las alturas)
                elseif startsWith(mensaje, "POS: ")
                    posicion = str2double(extractAfter(mensaje, "POS: "));
                    
                    if app.GuardandoAltura == "CERO"
                        app.AlturaCero = posicion;
                        app.MonitorTextArea.Value = [app.MonitorTextArea.Value; "> CERO FIJADO EN: " + string(posicion)];
                    elseif app.GuardandoAltura == "MAX"
                        app.AlturaMax = posicion;
                        app.MonitorTextArea.Value = [app.MonitorTextArea.Value; "> MÁXIMA FIJADA EN: " + string(posicion)];
                    elseif app.GuardandoAltura == "MIN"
                        app.AlturaMin = posicion;
                        app.MonitorTextArea.Value = [app.MonitorTextArea.Value; "> MÍNIMA FIJADA EN: " + string(posicion)];
                    end
                    
                    app.GuardandoAltura = ""; % Reseteamos
                    
                % 3. Cualquier otro mensaje va al monitor negro
                else
                    app.MonitorTextArea.Value = [app.MonitorTextArea.Value; mensaje];
                    scroll(app.MonitorTextArea, 'bottom');
                end
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BaudRateDropDown.Value = '115200';
        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)

            app.PortDropDown.Items = serialportlist("available");
          
%            puertosDisponibles = serialportlist("available");
            
            % Si encuentra algo, actualiza la lista. Si no, avisa.
%            if isempty(puertosDisponibles)
%                app.PortDropDown.Items = {'No connection available'};
%            else
%                app.PortDropDown.Items = puertosDisponibles;
%            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                % Leemos lo que el usuario ha seleccionado en los desplegables
                app.ESP32 = serialport(app.PortDropDown.Value, str2double(app.BaudRateDropDown.Value));
                
                % MUY IMPORTANTE: Le decimos a MATLAB que nuestros mensajes terminan con un "Enter" (CR/LF)
                configureTerminator(app.ESP32, "CR/LF");

                % Le decimos: "Cada vez que veas un terminator (CR/LF), ejecuta leerMensajeSerie"
                configureCallback(app.ESP32, "terminator", @app.leerMensajeSerie);
                
                % Opcional: Cambiamos la luz a verde y el texto del botón
                app.ConnectLamp.Color = 'green';
                app.ConnectButton.Text = 'Connected';
                app.ConnectButton.Enable = 'off'; % Desactivamos el botón para no conectar 2 veces

                % Creamos un temporizador que se ejecuta cada 0.5 segundos
                % Su única función es enviar la letra 'R' al ESP32
                app.TimerDatos = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @(~,~) writeline(app.ESP32, "R"));
                start(app.TimerDatos);
                
                
            catch ME
                % Si algo falla (ej. el puerto está en uso), sacamos una alerta
                uialert(app.UIFigure, "Error al conectar: " + ME.message, "Error de Conexión");
                app.ConnectLamp.Color = 'red';
                app.ConnectButton.Text = 'Error in connection';
            end
        end

        % Value changed function: ComandEditField
        function ComandEditFieldValueChanged(app, event)
            % 1. Leemos lo que has escrito y lo pasamos a mayúsculas por seguridad
            textoEscrito = upper(app.ComandEditField.Value);
            
            % 2. Verificamos que estamos conectados antes de enviar nada
            if isempty(app.ESP32) || ~isvalid(app.ESP32)
                uialert(app.UIFigure, "Conecta el puerto primero.", "Error");
                return;
            end
            
            % 3. ENVIAMOS EL COMANDO AL ESP32
            writeline(app.ESP32, textoEscrito);
            
            % 4. Mostramos en nuestro monitor lo que acabamos de enviar
            % Le pongo un "> " delante para diferenciar lo que enviamos de lo que recibimos
            app.MonitorTextArea.Value = [app.MonitorTextArea.Value; "> " + textoEscrito];
            scroll(app.MonitorTextArea, 'bottom');
            
            % 5. Limpiamos la barra de texto para que puedas escribir el siguiente rápido
            app.ComandEditField.Value = '';
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            writeline(app.ESP32,"U50");
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            writeline(app.ESP32,"D50")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ConfiguracinTab = uitab(app.TabGroup);
            app.ConfiguracinTab.Title = 'Configuración';
            app.PortDropDownLabel = uilabel(app.ConfiguracinTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConfiguracinTab);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.Placeholder = 'Avaliable Ports';
            app.PortDropDown.Value = {};
            app.BaudRateDropDownLabel = uilabel(app.ConfiguracinTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConfiguracinTab);
            app.BaudRateDropDown.Items = {'9600', '115200', '250000'};
            app.BaudRateDropDown.Placeholder = '115200';
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.ConfiguracinTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectLamp = uilamp(app.ConfiguracinTab);
            app.MonitorTextArea = uitextarea(app.ConfiguracinTab);
            app.MonitorTextArea.Editable = 'off';
            app.MonitorTextArea.Value = {'CONSOLE:'};
            app.ComandEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.ComandEditFieldLabel.Text = 'Comand';
            app.ComandEditField = uieditfield(app.ConfiguracinTab, 'text');
            app.ComandEditField.ValueChangedFcn = createCallbackFcn(app, @ComandEditFieldValueChanged, true);
            app.SetMaxButton = uibutton(app.ConfiguracinTab, 'push');
            app.SetMaxButton.Text = 'SetMax';
            app.SetMinButton = uibutton(app.ConfiguracinTab, 'push');
            app.SetMinButton.Text = 'SetMin';
            app.Set0Button = uibutton(app.ConfiguracinTab, 'push');
            app.Set0Button.Text = 'Set0';
            app.UpButton = uibutton(app.ConfiguracinTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '↑';
            app.DownButton = uibutton(app.ConfiguracinTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '↓';
            app.SetHeigthsButton = uibutton(app.ConfiguracinTab, 'push');
            app.SetHeigthsButton.Text = 'Set Heigths';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlexBenchApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end